classdef Cizek_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        VrhsvislTab                    matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        DobavstuputlesaTextArea        matlab.ui.control.TextArea
        DobavstuputlesaTextAreaLabel   matlab.ui.control.Label
        MaximlnvkatlesaTextArea        matlab.ui.control.TextArea
        MaximlnvkatlesaLabel           matlab.ui.control.Label
        VkatlesaTextArea               matlab.ui.control.TextArea
        VkatlesaTextAreaLabel          matlab.ui.control.Label
        PotenrychlostEditField         matlab.ui.control.NumericEditField
        PotenrychlostEditFieldLabel    matlab.ui.control.Label
        UraendrhaTextArea              matlab.ui.control.TextArea
        UraendrhaTextAreaLabel         matlab.ui.control.Label
        PotmButtonGroup                matlab.ui.container.ButtonGroup
        VrhsvislvzhruButton            matlab.ui.control.RadioButton
        VrhsvisldolButton              matlab.ui.control.RadioButton
        VolnpdButton                   matlab.ui.control.RadioButton
        VslednrychlostTextArea         matlab.ui.control.TextArea
        VslednrychlostTextAreaLabel    matlab.ui.control.Label
        SpotejButton                   matlab.ui.control.Button
        asEditField                    matlab.ui.control.NumericEditField
        asEditFieldLabel               matlab.ui.control.Label
        GravitanzrychlenButtonGroup    matlab.ui.container.ButtonGroup
        MarsButton                     matlab.ui.control.RadioButton
        MscButton                      matlab.ui.control.RadioButton
        ZemButton                      matlab.ui.control.RadioButton
        VrhikmTab                      matlab.ui.container.Tab
        Panel3                         matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        Panel2                         matlab.ui.container.Panel
        SpotejButton_2                 matlab.ui.control.Button
        VzdlenostTextArea              matlab.ui.control.TextArea
        VzdlenostTextAreaLabel         matlab.ui.control.Label
        MaximlnvkaTextArea             matlab.ui.control.TextArea
        MaximlnvkaLabel                matlab.ui.control.Label
        GravitanzrychlenButtonGroup_2  matlab.ui.container.ButtonGroup
        MarsButton_2                   matlab.ui.control.RadioButton
        MscButton_2                    matlab.ui.control.RadioButton
        ZemButton_2                    matlab.ui.control.RadioButton
        helEditField                   matlab.ui.control.NumericEditField
        helEditFieldLabel              matlab.ui.control.Label
        PotenrychlostEditField_2       matlab.ui.control.NumericEditField
        PotenrychlostEditField_2Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Cas % Description
        PocRych % Description
        Uhel % Description
        Grav % Description
        Rych % Description
        Draha % Description
        Vyska % Description
        MaxVyska % Description
        Vystup % Description
        Dostrel % Description
        PocRych2 % Description
        Grav2 % Description
        MaxVyska2 % Description
        Button % Description
        GButton % Description
        GButton2 % Description
    end
    
    methods (Access = private)
        
        function Plot(app)
            a=app.Uhel;
            v=app.PocRych2;
            g=app.Grav2;
            app.Dostrel=v^2*sind(2*a)/g;
            d = app.Dostrel;
            x=linspace(0,d,100);

            y=x.*tand(a) - ((x.^2).*g)./(2*(v^2)*cosd(a)^2);

            plot(app.UIAxes,x,y)
            axis(app.UIAxes,'equal')
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'left';
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Cas = 1;
            app.PocRych = 10;
            app.Grav = 9.80665;

            app.Button = app.VrhsvislvzhruButton;
            app.asEditField.Value = app.Cas;
            app.PotenrychlostEditField.Value = app.PocRych;
            app.SpotejButtonPushed;

            app.PocRych2 = 10;
            app.Uhel = 45;
            app.Grav2 = 9.80665;

            app.PotenrychlostEditField_2.Value = app.PocRych2;
            app.helEditField.Value = app.Uhel;
            app.SpotejButton_2Pushed;

        end

        % Value changed function: asEditField
        function asEditFieldValueChanged(app, event)
            app.Cas = app.asEditField.Value;
            
        end

        % Value changed function: helEditField
        function helEditFieldValueChanged(app, event)
            app.Uhel = app.helEditField.Value;
            
        end

        % Selection changed function: PotmButtonGroup
        function PotmButtonGroupSelectionChanged(app, event)
            app.Button = app.PotmButtonGroup.SelectedObject;
            
        end

        % Button pushed function: SpotejButton
        function SpotejButtonPushed(app, event)
             if app.Button == app.VolnpdButton
                app.Rych = app.Grav*app.Cas;
                app.Draha = (app.Grav*app.Cas^2)/2;
                app.Vyska = 0; app.MaxVyska = 0; app.Vystup = 0;
            elseif app.Button == app.VrhsvisldolButton
                app.Rych = app.Grav*app.Cas + app.PocRych;
                app.Draha = (app.Grav*app.Cas^2)/2 + app.PocRych*app.Cas;
                app.Vyska = 0; app.MaxVyska = 0; app.Vystup = 0;
            elseif app.Button == app.VrhsvislvzhruButton
                app.Rych = app.PocRych - app.Grav*app.Cas;
                app.Vyska = app.PocRych*app.Cas - (app.Grav*app.Cas^2)/2;
                app.MaxVyska = (app.PocRych^2)/(2*app.Grav);
                app.Vystup = app.PocRych/app.Grav;
                app.Draha = 0;
             end

             app.VslednrychlostTextArea.Value = num2str(app.Rych);
             app.UraendrhaTextArea.Value = num2str(app.Draha);
             app.VkatlesaTextArea.Value = num2str(app.Vyska);
             app.MaximlnvkatlesaTextArea.Value = num2str(app.MaxVyska);
             app.DobavstuputlesaTextArea.Value = num2str(app.Vystup);
        end

        % Button pushed function: SpotejButton_2
        function SpotejButton_2Pushed(app, event)
            Plot(app);
            app.MaxVyska2 = (app.PocRych2^2)*(sind(app.Uhel)^2)/(2*app.Grav2);
            app.MaximlnvkaTextArea.Value = num2str(app.MaxVyska2);
            app.VzdlenostTextArea.Value = num2str(app.Dostrel);
        end

        % Value changed function: PotenrychlostEditField
        function PotenrychlostEditFieldValueChanged(app, event)
            app.PocRych = app.PotenrychlostEditField.Value;
            
        end

        % Value changed function: PotenrychlostEditField_2
        function PotenrychlostEditField_2ValueChanged(app, event)
            app.PocRych2 = app.PotenrychlostEditField_2.Value;
            
        end

        % Selection changed function: GravitanzrychlenButtonGroup
        function GravitanzrychlenButtonGroupSelectionChanged(app, event)
            app.GButton = app.GravitanzrychlenButtonGroup.SelectedObject;
            
            if app.GButton == app.ZemButton
                app.Grav = 9.80665;
            elseif app.GButton == app.MscButton
                app.Grav = 1.625;
            elseif app.GButton == app.MarsButton
                app.Grav = 3.728;
            end
        end

        % Selection changed function: GravitanzrychlenButtonGroup_2
        function GravitanzrychlenButtonGroup_2SelectionChanged(app, event)
            app.GButton2 = app.GravitanzrychlenButtonGroup_2.SelectedObject;
            
            if app.GButton2 == app.ZemButton_2
                app.Grav2 = 9.80665;
            elseif app.GButton2 == app.MscButton_2
                app.Grav2 = 1.625;
            elseif app.GButton2 == app.MarsButton_2
                app.Grav2 = 3.728;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VrhsvislTab = uitab(app.TabGroup);
            app.VrhsvislTab.Title = 'Vrh svislý';
            app.Panel = uipanel(app.VrhsvislTab);
            app.Panel.Title = 'Panel';
            app.GravitanzrychlenButtonGroup = uibuttongroup(app.Panel);
            app.GravitanzrychlenButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GravitanzrychlenButtonGroupSelectionChanged, true);
            app.GravitanzrychlenButtonGroup.Title = 'Gravitační zrychlení';
            app.ZemButton = uiradiobutton(app.GravitanzrychlenButtonGroup);
            app.ZemButton.Text = 'Země';
            app.ZemButton.Value = true;
            app.MscButton = uiradiobutton(app.GravitanzrychlenButtonGroup);
            app.MscButton.Text = 'Měsíc';
            app.MarsButton = uiradiobutton(app.GravitanzrychlenButtonGroup);
            app.MarsButton.Text = 'Mars';
            app.asEditFieldLabel = uilabel(app.Panel);
            app.asEditFieldLabel.Text = 'Čas';
            app.asEditField = uieditfield(app.Panel, 'numeric');
            app.asEditField.ValueChangedFcn = createCallbackFcn(app, @asEditFieldValueChanged, true);
            app.SpotejButton = uibutton(app.Panel, 'push');
            app.SpotejButton.ButtonPushedFcn = createCallbackFcn(app, @SpotejButtonPushed, true);
            app.SpotejButton.Text = 'Spočítej';
            app.VslednrychlostTextAreaLabel = uilabel(app.Panel);
            app.VslednrychlostTextAreaLabel.Text = 'Výsledná rychlost';
            app.VslednrychlostTextArea = uitextarea(app.Panel);
            app.VslednrychlostTextArea.Value = {'0'};
            app.PotmButtonGroup = uibuttongroup(app.Panel);
            app.PotmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PotmButtonGroupSelectionChanged, true);
            app.PotmButtonGroup.Title = 'Počítám:';
            app.VolnpdButton = uiradiobutton(app.PotmButtonGroup);
            app.VolnpdButton.Text = 'Volný pád';
            app.VrhsvisldolButton = uiradiobutton(app.PotmButtonGroup);
            app.VrhsvisldolButton.Text = 'Vrh svislý dolů';
            app.VrhsvislvzhruButton = uiradiobutton(app.PotmButtonGroup);
            app.VrhsvislvzhruButton.Text = 'Vrh svislý vzhůru';
            app.VrhsvislvzhruButton.Value = true;
            app.UraendrhaTextAreaLabel = uilabel(app.Panel);
            app.UraendrhaTextAreaLabel.Text = 'Uražená dráha';
            app.UraendrhaTextArea = uitextarea(app.Panel);
            app.UraendrhaTextArea.Value = {'0'};
            app.PotenrychlostEditFieldLabel = uilabel(app.Panel);
            app.PotenrychlostEditFieldLabel.Text = 'Počáteční rychlost';
            app.PotenrychlostEditField = uieditfield(app.Panel, 'numeric');
            app.PotenrychlostEditField.ValueChangedFcn = createCallbackFcn(app, @PotenrychlostEditFieldValueChanged, true);
            app.VkatlesaTextAreaLabel = uilabel(app.Panel);
            app.VkatlesaTextAreaLabel.Text = 'Výška tělesa';
            app.VkatlesaTextArea = uitextarea(app.Panel);
            app.VkatlesaTextArea.Value = {'0'};
            app.MaximlnvkatlesaLabel = uilabel(app.Panel);
            app.MaximlnvkatlesaLabel.Text = 'Maximální výška tělesa';
            app.MaximlnvkatlesaTextArea = uitextarea(app.Panel);
            app.MaximlnvkatlesaTextArea.Value = {'0'};
            app.DobavstuputlesaTextAreaLabel = uilabel(app.Panel);
            app.DobavstuputlesaTextAreaLabel.Text = 'Doba výstupu tělesa';
            app.DobavstuputlesaTextArea = uitextarea(app.Panel);
            app.DobavstuputlesaTextArea.Value = {'0'};
            app.VrhikmTab = uitab(app.TabGroup);
            app.VrhikmTab.Title = 'Vrh šikmý';
            app.Panel2 = uipanel(app.VrhikmTab);
            app.Panel2.Title = 'Panel2';
            app.PotenrychlostEditField_2Label = uilabel(app.Panel2);
            app.PotenrychlostEditField_2Label.Text = 'Počáteční rychlost';
            app.PotenrychlostEditField_2 = uieditfield(app.Panel2, 'numeric');
            app.PotenrychlostEditField_2.ValueChangedFcn = createCallbackFcn(app, @PotenrychlostEditField_2ValueChanged, true);
            app.helEditFieldLabel = uilabel(app.Panel2);
            app.helEditFieldLabel.Text = 'Úhel';
            app.helEditField = uieditfield(app.Panel2, 'numeric');
            app.helEditField.ValueChangedFcn = createCallbackFcn(app, @helEditFieldValueChanged, true);
            app.GravitanzrychlenButtonGroup_2 = uibuttongroup(app.Panel2);
            app.GravitanzrychlenButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @GravitanzrychlenButtonGroup_2SelectionChanged, true);
            app.GravitanzrychlenButtonGroup_2.Title = 'Gravitační zrychlení';
            app.ZemButton_2 = uiradiobutton(app.GravitanzrychlenButtonGroup_2);
            app.ZemButton_2.Text = 'Země';
            app.ZemButton_2.Value = true;
            app.MscButton_2 = uiradiobutton(app.GravitanzrychlenButtonGroup_2);
            app.MscButton_2.Text = 'Měsíc';
            app.MarsButton_2 = uiradiobutton(app.GravitanzrychlenButtonGroup_2);
            app.MarsButton_2.Text = 'Mars';
            app.MaximlnvkaLabel = uilabel(app.Panel2);
            app.MaximlnvkaLabel.Text = 'Maximální výška';
            app.MaximlnvkaTextArea = uitextarea(app.Panel2);
            app.MaximlnvkaTextArea.Value = {'0'};
            app.VzdlenostTextAreaLabel = uilabel(app.Panel2);
            app.VzdlenostTextAreaLabel.Text = 'Vzdálenost';
            app.VzdlenostTextArea = uitextarea(app.Panel2);
            app.VzdlenostTextArea.Value = {'0'};
            app.SpotejButton_2 = uibutton(app.Panel2, 'push');
            app.SpotejButton_2.ButtonPushedFcn = createCallbackFcn(app, @SpotejButton_2Pushed, true);
            app.SpotejButton_2.Text = 'Spočítej';
            app.Panel3 = uipanel(app.VrhikmTab);
            app.Panel3.Title = 'Panel3';
            app.UIAxes = uiaxes(app.Panel3);
            title(app.UIAxes, 'Trajektorie')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cizek_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end